classdef LabelCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        LoadFileButton                  matlab.ui.control.Button
        FileNameLabel                   matlab.ui.control.Label
        FileNameDisplay                 matlab.ui.control.Label
        LabelPanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        SelectAllButton                 matlab.ui.control.Button
        SelectUnfilledButton            matlab.ui.control.Button
        DeselectAllButton               matlab.ui.control.Button
        SelectAllButton_2               matlab.ui.control.Button
        SelectUnfilledButton_2          matlab.ui.control.Button
        DeselectAllButton_2             matlab.ui.control.Button
        DeselectAllButton_3             matlab.ui.control.Button
        DeselectAllButton_4             matlab.ui.control.Button
        DeselectAllButton_5             matlab.ui.control.Button
        SelectUnfilledButton_3          matlab.ui.control.Button
        SelectAllButton_3               matlab.ui.control.Button
        SelectAllButton_4               matlab.ui.control.Button
        SelectAllButton_5               matlab.ui.control.Button
        SelectUnfilledButton_4          matlab.ui.control.Button
        SelectUnfilledButton_5          matlab.ui.control.Button
        SelectChannelsPanel             matlab.ui.container.Panel
        SelectChannelsButton            matlab.ui.control.Button
        EditPanel                       matlab.ui.container.Panel
        ChannelEditFieldLabel           matlab.ui.control.Label
        ChannelEditField                matlab.ui.control.EditField
        SetChannelButton                matlab.ui.control.Button
        SetLabelandLabelIDButton        matlab.ui.control.Button
        LabelEditFieldLabel             matlab.ui.control.Label
        LabelEditField                  matlab.ui.control.EditField
        LabelIDEditFieldLabel           matlab.ui.control.Label
        LabelIDEditField                matlab.ui.control.EditField
        SetDateButton                   matlab.ui.control.Button
        DateEditFieldLabel              matlab.ui.control.Label
        DateEditField                   matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        NotesEditField                  matlab.ui.control.EditField
        SetNotesButton                  matlab.ui.control.Button
        SetRecordingSessionButton       matlab.ui.control.Button
        RecordingSessionEditFieldLabel  matlab.ui.control.Label
        RecordingSessionEditField       matlab.ui.control.EditField
        GenerateCSVPanel                matlab.ui.container.Panel
        CSVNameNoExtensionEditFieldLabel  matlab.ui.control.Label
        CSVNameNoExtensionEditField     matlab.ui.control.EditField
        GenerateCSVButton               matlab.ui.control.Button
        AppendtoExistingCSVButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %% Set visibility off for table functions so user can select channels to be labeled first         
                    set(app.EditPanel, 'visible', 'off');
                    set(app.SelectAllButton_2, 'visible', 'off');
                    set(app.SelectAllButton_3, 'visible', 'off');
                    set(app.SelectAllButton_4, 'visible', 'off');
                    set(app.SelectAllButton_5, 'visible', 'off');
                    set(app.DeselectAllButton_2, 'visible', 'off');
                    set(app.DeselectAllButton_3, 'visible', 'off');
                    set(app.DeselectAllButton_4, 'visible', 'off');
                    set(app.DeselectAllButton_5, 'visible', 'off');
                    set(app.SelectUnfilledButton, 'visible', 'off');
                    set(app.SelectUnfilledButton_2, 'visible', 'off');
                    set(app.SelectUnfilledButton_3, 'visible', 'off');
                    set(app.SelectUnfilledButton_4, 'visible', 'off');
                    set(app.SelectUnfilledButton_5, 'visible', 'off');
                    set(app.GenerateCSVPanel, 'visible', 'off');
                    set(app.SelectChannelsPanel, 'visible', 'on');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
           %Retrieve file from directory as selected by user
                    set(app.EditPanel, 'visible', 'off');
                    set(app.SelectAllButton_2, 'visible', 'off');
                    set(app.SelectAllButton_3, 'visible', 'off');
                    set(app.SelectAllButton_4, 'visible', 'off');
                    set(app.SelectAllButton_5, 'visible', 'off');
                    set(app.DeselectAllButton_2, 'visible', 'off');
                    set(app.DeselectAllButton_3, 'visible', 'off');
                    set(app.DeselectAllButton_4, 'visible', 'off');
                    set(app.DeselectAllButton_5, 'visible', 'off');
                    set(app.SelectUnfilledButton, 'visible', 'off');
                    set(app.SelectUnfilledButton_2, 'visible', 'off');
                    set(app.SelectUnfilledButton_3, 'visible', 'off');
                    set(app.SelectUnfilledButton_4, 'visible', 'off');
                    set(app.SelectUnfilledButton_5, 'visible', 'off');
                    set(app.GenerateCSVPanel, 'visible', 'off');
                    set(app.SelectChannelsPanel, 'visible', 'on');
            [File,Path] = uigetfile;
            File = strcat(Path, '/', File);
            [~, File_Name, Ext] = fileparts(File);
            app.FileNameDisplay.Text = File_Name;
            %Load the sig channel names for the different file types
            switch lower(Ext)
                case '.mat'
                    File_Path = strcat(Path, File);
                    load(File_Path,'channel_map');
                    Sig_Channels = channel_map(:,1);
                case {'.plx', '.pl2'}
                    [tscounts, ~, evcounts, ~] = plx_info(File,1);
                    [tot_units, tot_channels] = size(tscounts);
                    [~, channel_names] = plx_chan_names(File);
                    subchan = {'i','a','b','c','d'};
                    channel_map = [];
                    for unit_i = 0:tot_units - 1 % Start at 0 for unsorted 
                        for channel_i = 1:tot_channels - 1
                            if (tscounts(unit_i + 1, channel_i + 1) > 0)
                                %% get the timestamps for this channel and unit 
                                [~, channel_timestamps] = plx_ts(File, channel_i, unit_i);
                                % channel_names is a char array
                                current_channel = channel_names(channel_i, :);
                                split_channel = strsplit(current_channel, ' ');
                                current_channel = split_channel{1};
                                current_channel = deblank(current_channel);
                                current_subchan = subchan{unit_i + 1};
                                complete_channel_name = [current_channel, current_subchan];
                                channel_map = [channel_map; {complete_channel_name}, {channel_timestamps}];
                            end
                        end
                    end
                    channel_map = sortrows(channel_map, 1);
                    Sig_Channels = channel_map(:,1);
               otherwise error('Unexpected file extension: %s', Ext);
            end
            %Set variables to generate a UI table and checkboxes to select which channel to label
                    Length = length(Sig_Channels);
                    T_Selection(:,1:Length) = true;
                    Select_Channels_To_Use = T_Selection';
                    app.UITable.Data = [cell2table(Sig_Channels), array2table(Select_Channels_To_Use)];
             
        end

        % Button pushed function: SetChannelButton
        function SetChannelButtonPushed(app, event)
          %Button is used to set user channel name from input into text entry field
            L = size(app.UITable.Data, 1);
            if strcmp(app.ChannelEditField.Value, '')
               f = errordlg('Channel Name is Blank','Channel Error'); 
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,2)) == true
                            app.UITable.Data(i,3) = cellstr(app.ChannelEditField.Value);
                            app.UITable.Data(i,2) = array2table(false);
                    end
                end
            app.ChannelEditField.Value = '';   
            end
        end

        % Button pushed function: SetLabelandLabelIDButton
        function SetLabelandLabelIDButtonPushed(app, event)
           %% Button used to set Label and Label ID from edit field to selected channels
            L = size(app.UITable.Data, 1);
            if strcmp(app.LabelEditField.Value, '') || strcmp(app.LabelIDEditField.Value, '')
                f = errordlg('Label/Label ID is Blank','Labeling Error');
            elseif isempty(str2num(app.LabelIDEditField.Value ))
                f = errordlg('LabelID is not Numerical Value', 'LabelID Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,4)) == true
                       app.UITable.Data(i,5) = cellstr(app.LabelEditField.Value);
                       app.UITable.Data(i,6) = cellstr(app.LabelIDEditField.Value);
                       app.UITable.Data(i,4) = array2table(false);
                    end   
                end
            app.LabelEditField.Value = '';
            app.LabelIDEditField.Value = '';
            end
        end

        % Button pushed function: SetRecordingSessionButton
        function SetRecordingSessionButtonPushed(app, event)
           %% Button used to set Recording session from edit field to selected channels 
            L = size(app.UITable.Data, 1);
            if strcmp(app.RecordingSessionEditField.Value, '')
                f = errordlg('Recording Session is Blank','Rec Session Error');
            elseif isempty(str2num(app.RecordingSessionEditField.Value ))
                f = errordlg('Recording Session is not Numerical Value', 'Rec Session Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,7)) == true
                       app.UITable.Data(i,8) = cellstr(app.RecordingSessionEditField.Value);
                       app.UITable.Data(i,7) = array2table(false);
                    end
                end
            app.RecordingSessionEditField.Value = ''; 
            end
        end

        % Button pushed function: SetDateButton
        function SetDateButtonPushed(app, event)
           %% Button to manually set date to seleted channel labels
            L = size(app.UITable.Data, 1);
            if strcmp(app.DateEditField.Value, '')
                f = errordlg('Date is Blank','Date Error');
            elseif isempty(str2num(app.DateEditField.Value ))
                f = errordlg('Date is not Numerical Value', 'Date Error');
            elseif strlength(app.DateEditField.Value) ~= 8
                f = errordlg('Date is not correct format', 'Date Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,9)) == true
                       app.UITable.Data(i,10) = cellstr(app.DateEditField.Value);
                       app.UITable.Data(i,9) = array2table(false);
                    end
                end
            app.DateEditField.Value = ''; 
            end
        end

        % Button pushed function: SetNotesButton
        function SetNotesButtonPushed(app, event)
           %% Button to add notes to selected channels 
            L = size(app.UITable.Data, 1);
            if strcmp(app.NotesEditField.Value, '')
                f = errordlg('Notes is Blank','Notes Error');
            else
            for i = 1:L
                if table2array(app.UITable.Data(i,11)) == true
                   app.UITable.Data(i,12) = cellstr(app.NotesEditField.Value);
                   app.UITable.Data(i,11) = array2table(false);
                end
            end
            app.NotesEditField.Value = ''; 
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
           L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,2) = array2table(true);
            end
        end

        % Button pushed function: SelectAllButton_2
        function SelectAllButton_2Pushed(app, event)
           L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,4) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_3
        function SelectAllButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,7) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_4
        function SelectAllButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,9) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_5
        function SelectAllButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,11) = array2table(true);
            end 
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,2) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_2
        function DeselectAllButton_2Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,4) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_3
        function DeselectAllButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,7) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_4
        function DeselectAllButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,9) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_5
        function DeselectAllButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,11) = array2table(false);
            end
        end

        % Button pushed function: SelectUnfilledButton
        function SelectUnfilledButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,3))), '') == 1;
                    app.UITable.Data(i,2) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnfilledButton_2
        function SelectUnfilledButton_2Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,5))), '') == 1;
                    app.UITable.Data(i,4) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnfilledButton_3
        function SelectUnfilledButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,8))), '') == 1;
                    app.UITable.Data(i,7) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnfilledButton_4
        function SelectUnfilledButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,10))), '') == 1;
                    app.UITable.Data(i,9) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnfilledButton_5
        function SelectUnfilledButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,12))), '') == 1;
                    app.UITable.Data(i,11) = array2table(true);
                end
            end
        end

        % Button pushed function: AppendtoExistingCSVButton
        function AppendtoExistingCSVButtonPushed(app, event)
              %% Obtain data from the table columns after filling
                Sig_Channels = table2array(app.UITable.Data(:,1));
                Label = table2array(app.UITable.Data(:,5));
                Label_ID = table2array(app.UITable.Data(:,6));
                Channels = table2array(app.UITable.Data(:,3));
                Recording_Sessions = table2array(app.UITable.Data(:,8));
                Date = table2array(app.UITable.Data(:,10));
                Notes = table2array(app.UITable.Data(:,12));
                L = length(Sig_Channels);
           %% Setting error messages for incomplete filling of table
               for i = 1:L
               if strcmp(cellstr(table2cell(app.UITable.Data(i,3))), '') == 1;  
                  f = errordlg('Table not filled','Label Error'); 
                  break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,5))),'') == 1;
                    f = errordlg('Table not filled','Label Error'); 
                    break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,6))),'') == 1 ;
                   f = errordlg('Table not filled','Label Error'); 
                   break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,8))),'') == 1;
                   f = errordlg('Table not filled','Label Error'); 
                   break
               else
            %% Loading the previous labels csv, adding columns names again, appending new data, and writing new csv  
                 ColumnName = {'sig_channels', 'user_channels', 'label', 'label_id', 'recording_session', 'date', 'recording_notes'};
                 Data_Table = table(Sig_Channels, Channels, Label, Label_ID, Recording_Sessions, Date, Notes);
                 [File_Name, ~] = uigetfile({'*.csv'});
                 File = readtable(File_Name);
                 Table_CSV_Read = standardizeMissing(File, {'NaN'});
                 Table_CSV_Read = table2cell(Table_CSV_Read);
                 Data_Table = table2cell(Data_Table);
                 Final_Table = [ColumnName; Table_CSV_Read; Data_Table];
                 Final_Table = cell2table(Final_Table);
                 writetable(Final_Table, File_Name,'WriteVariableNames', false); 
                 f = msgbox('LabelCSV appended');
                 app.CSVNameNoExtensionEditField.Value = ''; 
                 break
                 break
               end
           end
        end

        % Button pushed function: GenerateCSVButton
        function GenerateCSVButtonPushed(app, event)
            %% Obtain data from table after it is filled   
            Sig_Channels = table2array(app.UITable.Data(:,1));
                Label = table2array(app.UITable.Data(:,5));
                Label_ID = table2array(app.UITable.Data(:,6));
                Channels = table2array(app.UITable.Data(:,3));
                Recording_Sessions = table2array(app.UITable.Data(:,8));
                Date = table2array(app.UITable.Data(:,10));
                Notes = table2array(app.UITable.Data(:,12));
           L = length(Sig_Channels);
          %% Setting error messages for incomplete table
           for i = 1:L
               if strcmp(cellstr(table2cell(app.UITable.Data(i,3))), '') == 1;  
                  f = errordlg('Table not filled','Label Error'); 
                  break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,5))),'') == 1;
                    f = errordlg('Table not filled','Label Error'); 
                    break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,6))),'') == 1 ;
                   f = errordlg('Table not filled','Label Error'); 
                   break
               elseif strcmp(cellstr(table2cell(app.UITable.Data(i,8))),'') == 1;
                   f = errordlg('Table not filled','Label Error'); 
                   break
               else
                   % Creating the column names, and writing data to csv
           Data_Table = table(Sig_Channels,Channels, Label, Label_ID, Recording_Sessions, Date, Notes);
           ColumnName = {'sig_channels', 'user_channels', 'label', 'label_id', 'recording_session', 'date', 'recording_notes'};
           Final_Table = [ColumnName; Data_Table];
              if strcmp(app.CSVNameNoExtensionEditField.Value,'')
                     f = errordlg('Labels.csv Name Not Specified','Label Error');
                  else
                    CSV_Name_string = strcat(app.CSVNameNoExtensionEditField.Value,'.Labels.csv');
                     writetable(Final_Table, CSV_Name_string, 'WriteVariableNames', false);
                     f = msgbox('LabelCSV generated');
                     app.CSVNameNoExtensionEditField.Value = '';
              end
              break
              break
               end
           end
        end

        % Value changed function: CSVNameNoExtensionEditField
        function CSVNameNoExtensionEditFieldValueChanged(app, event)
            value = app.CSVNameNoExtensionEditField.Value;
            
        end

        % Button pushed function: SelectChannelsButton
        function SelectChannelsButtonPushed(app, event)
           %% After the initial channel selection, creating table with checkboxes and columns to enter all data
           %% Making all the buttons and test entry fields visible for data entry
            L = size(app.UITable.Data,1);
            for i = 1:L
                if table2array(app.UITable.Data(i,2)) == false;
                    app.UITable.Data(i,1) = cellstr('');
                end
            end
            if  count(app.FileNameDisplay.Text, '_') == 4;
                try
                    R_S = extractBetween(app.FileNameDisplay.Text, '_','_');
                    R_S2 = erase(extractBetween(app.FileNameDisplay.Text, R_S(1), R_S(2)),'_')
                    Sig_Channels = table2array(app.UITable.Data(:,1));
                    Sig_Channels = Sig_Channels(~cellfun(@isempty, Sig_Channels));
                    Length = length(Sig_Channels);
                    Selection(:,1:Length)= false;
                    Region_Selection = Selection';
                    Region = repmat({''}, Length, 1);
                    Region_ID = repmat({''}, Length, 1);
                    Channel_Selection = Selection';
                    Channel = repmat({''}, Length, 1);
                    Rec_Session_Selection = Selection';
                    Rec_Session = repmat({R_S2}, Length, 1);
                    Date_Selection = Selection';
                    Date = repmat({R_S(2)}, Length, 1);
                    Notes_Selection = Selection';
                    Notes = repmat({''}, Length, 1);
                catch
                    Sig_Channels = table2array(app.UITable.Data(:,1));
                    Sig_Channels = Sig_Channels(~cellfun(@isempty, Sig_Channels));
                    Length = length(Sig_Channels);
                    Selection(:,1:Length)= false;
                    Region_Selection = Selection';
                    Region = repmat({''}, Length, 1);
                    Region_ID = repmat({''}, Length, 1);
                    Channel_Selection = Selection';
                    Channel = repmat({''}, Length, 1);
                    Rec_Session_Selection = Selection';
                    Rec_Session = repmat({''}, Length, 1);
                    Date_Selection = Selection';
                    Date = repmat({''}, Length, 1);
                    Notes_Selection = Selection';
                    Notes = repmat({''}, Length, 1);
                end
                   else
                    Sig_Channels = table2array(app.UITable.Data(:,1));
                    Sig_Channels = Sig_Channels(~cellfun(@isempty, Sig_Channels));
                    Length = length(Sig_Channels);
                    Selection(:,1:Length)= false;
                    Region_Selection = Selection';
                    Region = repmat({''}, Length, 1);
                    Region_ID = repmat({''}, Length, 1);
                    Channel_Selection = Selection';
                    Channel = repmat({''}, Length, 1);
                    Rec_Session_Selection = Selection';
                    Rec_Session = repmat({''}, Length, 1);
                    Date_Selection = Selection';
                    Date = repmat({''}, Length, 1);
                    Notes_Selection = Selection';
                    Notes = repmat({''}, Length, 1);
                end
                    app.UITable.Data = [cell2table(Sig_Channels), array2table(Channel_Selection), Channel, array2table(Region_Selection),Region,Region_ID, array2table(Rec_Session_Selection), Rec_Session,array2table(Date_Selection), Date, array2table(Notes_Selection), Notes];
                    set(app.EditPanel, 'visible', 'on');
                    set(app.SelectAllButton_2, 'visible', 'on');
                    set(app.SelectAllButton_3, 'visible', 'on');
                    set(app.SelectAllButton_4, 'visible', 'on');
                    set(app.SelectAllButton_5, 'visible', 'on');
                    set(app.DeselectAllButton_2, 'visible', 'on');
                    set(app.DeselectAllButton_3, 'visible', 'on');
                    set(app.DeselectAllButton_4, 'visible', 'on');
                    set(app.DeselectAllButton_5, 'visible', 'on');
                    set(app.SelectUnfilledButton, 'visible', 'on');
                    set(app.SelectUnfilledButton_2, 'visible', 'on');
                    set(app.SelectUnfilledButton_3, 'visible', 'on');
                    set(app.SelectUnfilledButton_4, 'visible', 'on');
                    set(app.SelectUnfilledButton_5, 'visible', 'on');
                    set(app.SelectChannelsPanel, 'visible', 'off');
                    set(app.GenerateCSVPanel, 'visible', 'on');
                   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FileNameLabel = uilabel(app.Panel);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameDisplay = uilabel(app.Panel);
            app.FileNameDisplay.Text = '';
            app.LabelPanel = uipanel(app.UIFigure);
            app.LabelPanel.Title = 'Label:';
            app.UITable = uitable(app.LabelPanel);
            app.UITable.ColumnName = {'sig_channels'; 'Set Channel'; 'user_channels'; 'Set Labels'; 'label'; 'label_id'; 'Set Rec_S'; 'recording_session'; 'Set Date'; 'date'; 'Set Notes'; 'recording_notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false true false false true false true false true false];
            app.SelectAllButton = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.SelectUnfilledButton = uibutton(app.LabelPanel, 'push');
            app.SelectUnfilledButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUnfilledButtonPushed, true);
            app.SelectUnfilledButton.Text = 'Select Unfilled';
            app.DeselectAllButton = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton_2 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_2Pushed, true);
            app.SelectAllButton_2.Text = 'Select All';
            app.SelectUnfilledButton_2 = uibutton(app.LabelPanel, 'push');
            app.SelectUnfilledButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectUnfilledButton_2Pushed, true);
            app.SelectUnfilledButton_2.Text = 'Select Unfilled';
            app.DeselectAllButton_2 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_2Pushed, true);
            app.DeselectAllButton_2.Text = 'Deselect All';
            app.DeselectAllButton_3 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_3Pushed, true);
            app.DeselectAllButton_3.Text = 'Deselect All';
            app.DeselectAllButton_4 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_4Pushed, true);
            app.DeselectAllButton_4.Text = 'Deselect All';
            app.DeselectAllButton_5 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_5Pushed, true);
            app.DeselectAllButton_5.Text = 'Deselect All';
            app.SelectUnfilledButton_3 = uibutton(app.LabelPanel, 'push');
            app.SelectUnfilledButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectUnfilledButton_3Pushed, true);
            app.SelectUnfilledButton_3.Text = 'Select Unfilled';
            app.SelectAllButton_3 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_3Pushed, true);
            app.SelectAllButton_3.Text = 'Select All';
            app.SelectAllButton_4 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_4Pushed, true);
            app.SelectAllButton_4.Text = 'Select All';
            app.SelectAllButton_5 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_5Pushed, true);
            app.SelectAllButton_5.Text = 'Select All';
            app.SelectUnfilledButton_4 = uibutton(app.LabelPanel, 'push');
            app.SelectUnfilledButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectUnfilledButton_4Pushed, true);
            app.SelectUnfilledButton_4.Text = 'Select Unfilled';
            app.SelectUnfilledButton_5 = uibutton(app.LabelPanel, 'push');
            app.SelectUnfilledButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectUnfilledButton_5Pushed, true);
            app.SelectUnfilledButton_5.Text = 'Select Unfilled';
            app.SelectChannelsPanel = uipanel(app.LabelPanel);
            app.SelectChannelsPanel.Title = 'Select Channels:';
            app.SelectChannelsButton = uibutton(app.SelectChannelsPanel, 'push');
            app.SelectChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectChannelsButtonPushed, true);
            app.SelectChannelsButton.Text = 'Select Channels';
            app.EditPanel = uipanel(app.LabelPanel);
            app.EditPanel.Title = 'Edit:';
            app.ChannelEditFieldLabel = uilabel(app.EditPanel);
            app.ChannelEditFieldLabel.Text = 'Channel:';
            app.ChannelEditField = uieditfield(app.EditPanel, 'text');
            app.SetChannelButton = uibutton(app.EditPanel, 'push');
            app.SetChannelButton.ButtonPushedFcn = createCallbackFcn(app, @SetChannelButtonPushed, true);
            app.SetChannelButton.Text = 'Set Channel';
            app.SetLabelandLabelIDButton = uibutton(app.EditPanel, 'push');
            app.SetLabelandLabelIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetLabelandLabelIDButtonPushed, true);
            app.SetLabelandLabelIDButton.Text = 'Set Label and Label ID';
            app.LabelEditFieldLabel = uilabel(app.EditPanel);
            app.LabelEditFieldLabel.Text = 'Label :';
            app.LabelEditField = uieditfield(app.EditPanel, 'text');
            app.LabelIDEditFieldLabel = uilabel(app.EditPanel);
            app.LabelIDEditFieldLabel.Text = 'Label ID:';
            app.LabelIDEditField = uieditfield(app.EditPanel, 'text');
            app.SetDateButton = uibutton(app.EditPanel, 'push');
            app.SetDateButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateButtonPushed, true);
            app.SetDateButton.Text = 'Set Date';
            app.DateEditFieldLabel = uilabel(app.EditPanel);
            app.DateEditFieldLabel.Text = 'Date:';
            app.DateEditField = uieditfield(app.EditPanel, 'text');
            app.NotesEditFieldLabel = uilabel(app.EditPanel);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.NotesEditField = uieditfield(app.EditPanel, 'text');
            app.SetNotesButton = uibutton(app.EditPanel, 'push');
            app.SetNotesButton.ButtonPushedFcn = createCallbackFcn(app, @SetNotesButtonPushed, true);
            app.SetNotesButton.Text = 'Set Notes';
            app.SetRecordingSessionButton = uibutton(app.EditPanel, 'push');
            app.SetRecordingSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SetRecordingSessionButtonPushed, true);
            app.SetRecordingSessionButton.Text = 'Set Recording Session';
            app.RecordingSessionEditFieldLabel = uilabel(app.EditPanel);
            app.RecordingSessionEditFieldLabel.Text = 'Recording Session:';
            app.RecordingSessionEditField = uieditfield(app.EditPanel, 'text');
            app.GenerateCSVPanel = uipanel(app.UIFigure);
            app.GenerateCSVPanel.Title = 'GenerateCSV:';
            app.CSVNameNoExtensionEditFieldLabel = uilabel(app.GenerateCSVPanel);
            app.CSVNameNoExtensionEditFieldLabel.Text = 'CSV Name (No Extension):';
            app.CSVNameNoExtensionEditField = uieditfield(app.GenerateCSVPanel, 'text');
            app.CSVNameNoExtensionEditField.ValueChangedFcn = createCallbackFcn(app, @CSVNameNoExtensionEditFieldValueChanged, true);
            app.GenerateCSVButton = uibutton(app.GenerateCSVPanel, 'push');
            app.GenerateCSVButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCSVButtonPushed, true);
            app.GenerateCSVButton.Text = 'Generate CSV';
            app.AppendtoExistingCSVButton = uibutton(app.GenerateCSVPanel, 'push');
            app.AppendtoExistingCSVButton.ButtonPushedFcn = createCallbackFcn(app, @AppendtoExistingCSVButtonPushed, true);
            app.AppendtoExistingCSVButton.Text = 'Append to Existing CSV';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LabelCSVGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end